classdef CvTask1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        EnhancementTab               matlab.ui.container.Tab
        TileSizeEditField            matlab.ui.control.NumericEditField
        TileSizeEditFieldLabel       matlab.ui.control.Label
        ClipLimitEditField           matlab.ui.control.NumericEditField
        ClipLimitEditFieldLabel      matlab.ui.control.Label
        UseCLAHECheckBox             matlab.ui.control.CheckBox
        EqualizeHistogramButton      matlab.ui.control.Button
        equalizeModeDropDown         matlab.ui.control.DropDown
        equalizeModeDropDownLabel    matlab.ui.control.Label
        resetBCGButton               matlab.ui.control.Button
        applyBCGButton               matlab.ui.control.Button
        gammaSlider                  matlab.ui.control.Slider
        gammaSliderLabel             matlab.ui.control.Label
        contrastSlider               matlab.ui.control.Slider
        contrastSliderLabel          matlab.ui.control.Label
        brightnessSlider             matlab.ui.control.Slider
        brightnessSliderLabel        matlab.ui.control.Label
        SpatialTab                   matlab.ui.container.Tab
        ApplySpatialButton           matlab.ui.control.Button
        sigmaField                   matlab.ui.control.NumericEditField
        SigmaStrengthEditFieldLabel  matlab.ui.control.Label
        kernelSizeField              matlab.ui.control.NumericEditField
        KernalSizeEditFieldLabel     matlab.ui.control.Label
        spatialFilterDrop            matlab.ui.control.DropDown
        spatialFilterDropDownLabel   matlab.ui.control.Label
        FrequencyTab                 matlab.ui.container.Tab
        ApplyFrequencyButton         matlab.ui.control.Button
        orderField                   matlab.ui.control.NumericEditField
        OrdernEditFieldLabel         matlab.ui.control.Label
        cutoffField                  matlab.ui.control.NumericEditField
        CutoffD0EditFieldLabel       matlab.ui.control.Label
        passTypeDropDown             matlab.ui.control.DropDown
        passTypeDropDownLabel        matlab.ui.control.Label
        freqTypeDropDown             matlab.ui.control.DropDown
        freqTypeDropDownLabel        matlab.ui.control.Label
        ColorTab                     matlab.ui.container.Tab
        backToRGBButtonPushed        matlab.ui.control.Button
        toYCbCrButtonPushed          matlab.ui.control.Button
        toLabButtonPushed            matlab.ui.control.Button
        toHSIButtonPushed            matlab.ui.control.Button
        AnalyzeTab                   matlab.ui.container.Tab
        StatusLabel                  matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        LoadButton                   matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
     ImgOriginal uint8 = [];   % original image
    ImgWorking  uint8 = [];   % currently displayed result
     EnhBase uint8 = [];
      LastColorSpace (1,1) string = "RGB";
    RawColor struct = struct('space',"", 'data',[]);
        
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
try
    if isempty(app.ImgWorking)
        app.setStatus('Nothing to save. Load an image first.');
        return;
    end
    [f,p] = uiputfile({'*.png','PNG';'*.jpg','JPEG';'*.bmp','BMP';'*.tif','TIFF'}, ...
                      'Save result as','result.png');
    if isequal(f,0)
        app.setStatus('Save canceled.');
        return;
    end
    imwrite(app.ImgWorking, fullfile(p,f));
    app.setStatus('Saved: ' + string(f));
catch ME
    app.setStatus('Save failed.');
    uialert(app.UIFigure, ME.message, 'Save Error');
end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            try
    if isempty(app.ImgOriginal)
        app.setStatus('Nothing to reset. Load an image first.');
        return;
    end
    app.ImgWorking = app.ImgOriginal;
    app.showWorking();
    app.setStatus('Reset to original.');
catch ME
    app.setStatus('Reset failed.');
    uialert(app.UIFigure, ME.message, 'Reset Error');
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
         try
    app.setStatus('Loading...');
    % Let user pick an image
    [f,p] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp;*.tif','Image Files'}, ...
                      'Select an image');
    if isequal(f,0)
        app.setStatus('Load canceled.');
        return;
    end
    img = imread(fullfile(p,f));
    % Normalize to uint8 (common case)
    if ~isa(img,'uint8')
        img = im2uint8(img);
    end
    % Store and show
    app.ImgOriginal = img;
    app.ImgWorking  = img;
    app.showOriginal();
    app.showWorking();
    app.setStatus('Loaded: ' + string(f));
catch ME
    app.setStatus('Load failed.');
    uialert(app.UIFigure, ME.message, 'Load Error');
end
        end
        function showOriginal(app)
    if isempty(app.ImgOriginal)
        cla(app.UIAxes);
        title(app.UIAxes,'Original');
        return;
    end
    imshow(app.ImgOriginal, 'Parent', app.UIAxes);
    title(app.UIAxes,'Original');
end

function showWorking(app)
    if isempty(app.ImgWorking)
        cla(app.UIAxes2);
        title(app.UIAxes2,'Result');
        return;
    end
    imshow(app.ImgWorking, 'Parent', app.UIAxes2);
    title(app.UIAxes2,'Result');
end

function setStatus(app, msg)
    app.StatusLabel.Text = msg;
    drawnow; % ensure the label updates immediately
end

function J = applyBCG(app, Iin, B, C, G)
% Iin: uint8 RGB or gray
% B in [-100..100] (offset), C in [-100..100] (gain), G in [0.2..3] (gamma)
    I = im2double(Iin);

    % --- GAMMA ---
    % apply gamma first (I.^G); G<1 brightens midtones, G>1 darkens
    I = I .^ G;

    % --- CONTRAST ---
    % map contrast slider to gain factor; C=0 -> 1, C=100 -> 2, C=-100 -> 0
    gain = max(0, 1 + C/100);
    I = (I - 0.5) * gain + 0.5;

    % --- BRIGHTNESS ---
    % B in [-100..100] shifts by B/255
    beta = B/255;
    I = I + beta;

    % clip
    I = min(max(I,0),1);
    J = im2uint8(I);
end

function ensureEnhBase(app)
    if isempty(app.EnhBase)
        % take the current image as the starting point for B/C/G preview
        app.EnhBase = app.ImgWorking;
    end
end

function updateBCGPreview(app, B, C, G)
    if isempty(app.ImgWorking)
        app.setStatus('Load an image first.');
        return;
    end
    app.ensureEnhBase();
    base = app.EnhBase;
    J = app.applyBCG(base, B, C, G);
    app.ImgWorking = J;
    app.showWorking();
    app.setStatus(sprintf('B=%+d, C=%+d, \\gamma=%.2f', round(B), round(C), G));
end

function G = getGray(app)
    I = app.ImgWorking;
    if isempty(I), error('Load an image first.'); end
    if size(I,3)==3, G = rgb2gray(I); else, G = I; end
    G = im2double(G);
end

function I8 = ensureUint8(app, I)
    if ~isa(I,'uint8'), I8 = im2uint8(mat2gray(I)); else, I8 = I; end
end

function RGB = hsi2rgb_simple(app, HSI)
% H,S,I in [0,1]; returns uint8 RGB
    HSI = im2double(HSI);
    H = HSI(:,:,1) * 2*pi; S = HSI(:,:,2); I = HSI(:,:,3);
    R = zeros(size(I)); G = R; B = R;

    idx = (H >= 0) & (H < 2*pi/3);
    B(idx) = I(idx).*(1 - S(idx));
    R(idx) = I(idx).*(1 + S(idx).*cos(H(idx))./cos(pi/3 - H(idx) + eps));
    G(idx) = 3*I(idx) - (R(idx) + B(idx));

    idx = (H >= 2*pi/3) & (H < 4*pi/3);
    H2 = H(idx) - 2*pi/3;
    R(idx) = I(idx).*(1 - S(idx));
    G(idx) = I(idx).*(1 + S(idx).*cos(H2)./cos(pi/3 - H2 + eps));
    B(idx) = 3*I(idx) - (R(idx) + G(idx));

    idx = (H >= 4*pi/3) & (H < 2*pi);
    H3 = H(idx) - 4*pi/3;
    G(idx) = I(idx).*(1 - S(idx));
    B(idx) = I(idx).*(1 + S(idx).*cos(H3)./cos(pi/3 - H3 + eps));
    R(idx) = 3*I(idx) - (G(idx) + B(idx));

    RGB = im2uint8(min(max(cat(3,R,G,B),0),1));
end

function HSI = rgb2hsi_simple(app, RGB)
% Input: uint8 RGB; Output: double H,S,I in [0,1]
    I = im2double(RGB);
    R = I(:,:,1); G = I(:,:,2); B = I(:,:,3);
    num = 0.5*((R - G) + (R - B));
    den = sqrt( (R - G).^2 + (R - B).*(G - B) ) + eps;
    theta = acos( max(-1,min(1, num ./ den)) );
    H = theta; H(B>G) = 2*pi - H(B>G); H = H/(2*pi);       % [0,1]
    S = 1 - (3./(R+G+B+eps)).*min(min(R,G),B);              % [0,1]
    Ichan = (R+G+B)/3;                                      % [0,1]
    HSI = cat(3,H,S,Ichan);
end

function Ifilt = freqFilter2D(app, Iin, typeStr, passStr, D0, n)
% Iin: grayscale double [0..1]
% typeStr: "ideal" | "butterworth" | "gaussian"
% passStr: "low"   | "high"
% D0: cutoff radius (pixels), n: order for Butterworth (>=1)

    I = im2double(Iin);
    [M,N] = size(I);

    % Frequency grid centered at (0,0) after fftshift
    [U,V] = meshgrid( (-floor(N/2)):(ceil(N/2)-1), ...
                      (-floor(M/2)):(ceil(M/2)-1) );
    D = sqrt(U.^2 + V.^2);

    % Transfer function H
    switch lower(typeStr)
        case 'ideal'
            H = double(D <= max(D0,eps));
        case 'butterworth'
            D0 = max(D0,eps);
            n  = max(1, round(n));
            H = 1 ./ (1 + (D./D0).^(2*n));
        case 'gaussian'
            D0 = max(D0,eps);
            H = exp(-(D.^2) ./ (2*(D0^2)));
        otherwise
            error('Unknown frequency type: ' + string(typeStr));
    end
    if startsWith(lower(passStr),'h') % high-pass
        H = 1 - H;
    end

    % Filter in frequency domain
    F = fftshift(fft2(I));
    G = H .* F;
    g = real(ifft2(ifftshift(G)));

    Ifilt = im2uint8(mat2gray(g));
        end

        % Value changed function: brightnessSlider
        function brightnessSliderValueChanged2(app, event)
 app.updateBCGPreview(app.brightnessSlider.Value, app.contrastSlider.Value, app.gammaSlider.Value);
        
        end

        % Value changed function: contrastSlider
        function contrastSliderValueChanged2(app, event)
               app.updateBCGPreview(app.brightnessSlider.Value, app.contrastSlider.Value, app.gammaSlider.Value);

            
        end

        % Value changed function: gammaSlider
        function gammaSliderValueChanged2(app, event)
                app.updateBCGPreview(app.brightnessSlider.Value, app.contrastSlider.Value, app.gammaSlider.Value);

            
        end

        % Button pushed function: applyBCGButton
        function applyBCGButtonPushed(app, event)
            try
    app.EnhBase = app.ImgWorking;  % commit preview as new base
    app.setStatus('Applied Brightness/Contrast/Gamma.');
catch ME
    app.setStatus('Apply B/C/γ failed.');
    uialert(app.UIFigure, ME.message, 'Enhancement Error');
end
        end

        % Button pushed function: resetBCGButton
        function resetBCGButtonPushed(app, event)
            try
    app.brightnessSlider.Value = 0;
    app.contrastSlider.Value  = 0;
    app.gammaSlider.Value     = 1.0;
    app.EnhBase = app.ImgWorking;   % reset baseline
    app.updateBCGPreview(0,0,1.0);
    app.setStatus('B/C/γ reset.');
catch ME
    app.setStatus('Reset B/C/γ failed.');
    uialert(app.UIFigure, ME.message, 'Enhancement Error');
end
        end

        % Value changing function: brightnessSlider
        function brightnessSliderValueChanging(app, event)
               app.updateBCGPreview(event.Value, app.contrastSlider.Value, app.gammaSlider.Value);

            
        end

        % Value changing function: contrastSlider
        function contrastSliderValueChanging(app, event)
    app.updateBCGPreview(app.brightnessSlider.Value, event.Value, app.gammaSlider.Value);
            
        end

        % Value changing function: gammaSlider
        function gammaSliderValueChanging(app, event)
    app.updateBCGPreview(app.brightnessSlider.Value, app.contrastSlider.Value, event.Value);
            
        end

        % Button pushed function: ApplySpatialButton
        function ApplySpatialButtonPushed(app, event)
            try
    if isempty(app.ImgWorking), app.setStatus('Load an image first.'); return; end

    choice = string(app.spatialFilterDrop.Value);
    k  = max(3, round(app.kernelSizeField.Value));   % kernel size >=3
    if mod(k,2)==0, k = k+1; end                     % force odd
    s  = max(0.1, app.sigmaField.Value);             % sigma or strength

    Iin = app.ImgWorking;        % keep color when it makes sense
    out = [];                    % result holder

    switch choice
        case "Box Mean"
            h = ones(k,k) / (k*k);
            out = imfilter(Iin, h, 'replicate');

        case "Weighted Gaussian"
            h = fspecial('gaussian', [k k], s);
            out = imfilter(Iin, h, 'replicate');

        case "Median"
            if size(Iin,3)==3
                out = Iin;
                for c=1:3
                    out(:,:,c) = medfilt2(Iin(:,:,c), [k k], 'symmetric');
                end
            else
                out = medfilt2(Iin, [k k], 'symmetric');
            end

        case "Unsharp (Boost)"
            % classic unsharp: sharp = I + alpha*(I - blurred)
            alpha   = s;                           % use Sigma/Strength as alpha
            blurred = imgaussfilt(Iin, max(0.5, s));  % mild blur tied to s
            sharp   = im2double(Iin) + alpha*(im2double(Iin) - im2double(blurred));
            out     = im2uint8(mat2gray(sharp));

        case "Laplacian (2nd)"
            G = app.getGray();
            h = fspecial('laplacian', 0);         % alpha=0 classic 4-neighbor
            L = imfilter(G, h, 'replicate');
            out = app.ensureUint8(abs(L));        % visualize magnitude

        case "Sobel (H)"
            G = app.getGray();
            Sx = fspecial('sobel');
            out = app.ensureUint8(mat2gray(imfilter(G, Sx, 'replicate')));

        case "Sobel (V)"
            G = app.getGray();
            Sy = fspecial('sobel')';
            out = app.ensureUint8(mat2gray(imfilter(G, Sy, 'replicate')));

        case "Prewitt (H)"
            G = app.getGray();
            Px = fspecial('prewitt');
            out = app.ensureUint8(mat2gray(imfilter(G, Px, 'replicate')));

        case "Prewitt (V)"
            G = app.getGray();
            Py = fspecial('prewitt')';
            out = app.ensureUint8(mat2gray(imfilter(G, Py, 'replicate')));

        otherwise
            error('Unknown spatial filter.');
    end

    app.ImgWorking = out;
    app.showWorking();
    app.setStatus(sprintf('Spatial: %s (k=%d, σ/α=%.2g).', choice, k, s));

catch ME
    app.setStatus('Spatial filter failed.');
    uialert(app.UIFigure, ME.message, 'Spatial Error');
end
        end

        % Button pushed function: ApplyFrequencyButton
        function ApplyFrequencyButtonPushed(app, event)
            try
    if isempty(app.ImgWorking)
        app.setStatus('Load an image first.');
        return;
    end

    % Read UI (robust to small text changes)
    tRaw = string(app.freqTypeDropDown.Value);
    pRaw = string(app.passTypeDropDown.Value);

    % Normalize type
    t = lower(strtrim(tRaw));
    if startsWith(t,'i'),  t = 'ideal';
    elseif startsWith(t,'b'), t = 'butterworth';
    elseif startsWith(t,'g'), t = 'gaussian';
    else, error('Unknown type: ' + tRaw);
    end

    % Normalize pass
    p = lower(strtrim(pRaw));
    if startsWith(p,'h'),  p = 'high';
    else,                   p = 'low';
    end

    D0 = max(1, round(app.cutoffField.Value));
    n  = max(1, round(app.orderField.Value));   % used only for Butterworth

    % Prepare grayscale input
    G = app.getGray();

    % Apply
    out = app.freqFilter2D(G, t, p, D0, n);

    % Show + status
    app.ImgWorking = out;
    app.showWorking();
    app.setStatus(sprintf('Frequency: %s %s (D0=%d, n=%d).', upper(t), upper(p), D0, n));

catch ME
    app.setStatus('Frequency filter failed.');
    uialert(app.UIFigure, ME.message, 'Frequency Error');
end
        end

        % Button pushed function: backToRGBButtonPushed
        function backToRGBButtonPushedPushed(app, event)
    try
    if isempty(app.ImgWorking), error('Load first.'); end

    switch app.LastColorSpace
        case "YCbCr"
            RGB = ycbcr2rgb(app.RawColor.data);                  % invert true stored data
            app.ImgWorking = im2uint8(RGB);
            app.setStatus('Back to RGB from YCbCr.');

        case "HSI"
            RGB = app.hsi2rgb_simple(app.RawColor.data);         % invert true stored HSI
            app.ImgWorking = RGB;
            app.setStatus('Back to RGB from HSI.');

        case "Lab"
            app.ImgWorking = lab2rgb(app.RawColor.data, 'OutputType','uint8');
            app.setStatus('Back to RGB from L*a*b*.');

        otherwise
            app.ImgWorking = app.ensureUint8(app.ImgWorking);
            app.setStatus('Already in RGB (no color-space to revert).');
    end

    app.LastColorSpace = "RGB";
    app.RawColor = struct('space',"", 'data',[]);
    app.showWorking();

catch ME
    app.setStatus('Back to RGB failed.'); uialert(app.UIFigure,ME.message,'Color Error');
end
        end

        % Button pushed function: toYCbCrButtonPushed
        function toYCbCrButtonPushedPushed(app, event)
try
    if isempty(app.ImgWorking), error('Load first.'); end
    if size(app.ImgWorking,3)~=3, error('YCbCr expects RGB.'); end

    app.RawColor = struct('space',"YCbCr", 'data', rgb2ycbcr(app.ImgWorking));  % store true data
    app.ImgWorking = app.RawColor.data;                                         % display it
    app.LastColorSpace = "YCbCr";
    app.showWorking(); app.setStatus('Converted to YCbCr (invertible).');

catch ME
    app.setStatus('YCbCr conversion failed.'); uialert(app.UIFigure,ME.message,'Color Error');
end
        end

        % Button pushed function: toLabButtonPushed
        function toLabButtonPushedPushed(app, event)
try
    if isempty(app.ImgWorking), error('Load first.'); end
    if size(app.ImgWorking,3)~=3, error('L*a*b* expects RGB.'); end

    Lab = rgb2lab(app.ImgWorking);                           % L in [0..100], a,b ~[-128..127]
    app.RawColor = struct('space',"Lab", 'data', Lab);       % store true Lab
    % display-friendly scaling
    L = Lab(:,:,1)/100; 
    a = mat2gray(Lab(:,:,2),[-128 127]); 
    b = mat2gray(Lab(:,:,3),[-128 127]);
    app.ImgWorking = im2uint8(cat(3,L,a,b));
    app.LastColorSpace = "Lab";
    app.showWorking(); app.setStatus('Converted to L*a*b* (visualized, invertible via lab2rgb).');

catch ME
    app.setStatus('Lab conversion failed.'); uialert(app.UIFigure,ME.message,'Color Error');
end
        end

        % Button pushed function: toHSIButtonPushed
        function toHSIButtonPushedPushed(app, event)
 try
    if isempty(app.ImgWorking), error('Load first.'); end
    if size(app.ImgWorking,3)~=3, error('HSI expects RGB.'); end

    HSI = app.rgb2hsi_simple(app.ImgWorking);                 % true H,S,I in [0,1]
    app.RawColor = struct('space',"HSI", 'data', HSI);        % store true HSI
    app.ImgWorking = im2uint8(HSI);                           % visualize H,S,I
    app.LastColorSpace = "HSI";
    app.showWorking(); app.setStatus('Converted to HSI (visualized, invertible via HSI→RGB).');

catch ME
    app.setStatus('HSI conversion failed.'); uialert(app.UIFigure,ME.message,'Color Error');
end
        end

        % Button pushed function: EqualizeHistogramButton
        function EqualizeHistogramButtonPushed(app, event)
   try
    if isempty(app.ImgWorking)
        app.setStatus('Load an image first.');
        return;
    end

    mode = string(app.equalizeModeDropDown.Value);

    % Optional controls (safe defaults if you didn't add them)
    useCLAHE  = false; 
    clipLimit = 0.01; 
    tileSz    = 8;
    if isprop(app,'useCLAHECheckBox') && ~isempty(app.useCLAHECheckBox)
        useCLAHE = logical(app.useCLAHECheckBox.Value);
    end
    if isprop(app,'clipLimitField') && ~isempty(app.clipLimitField)
        clipLimit = max(0, app.clipLimitField.Value);
    end
    if isprop(app,'tileSizeField') && ~isempty(app.tileSizeField)
        tileSz = max(2, round(app.tileSizeField.Value));
    end

    I   = app.ImgWorking;
    out = [];

    switch mode
        case "Grayscale (auto)"
            if size(I,3)==3, Ig = rgb2gray(I); else, Ig = I; end
            if useCLAHE
                out = adapthisteq(Ig, 'ClipLimit', clipLimit, 'NumTiles', [tileSz tileSz]);
            else
                out = histeq(Ig);
            end

        case "Per-channel RGB"
            if size(I,3)~=3
                I = repmat(I,[1 1 3]);  % promote gray to RGB for consistency
            end
            out = I;
            if useCLAHE
                out(:,:,1) = adapthisteq(out(:,:,1), 'ClipLimit', clipLimit, 'NumTiles', [tileSz tileSz]);
                out(:,:,2) = adapthisteq(out(:,:,2), 'ClipLimit', clipLimit, 'NumTiles', [tileSz tileSz]);
                out(:,:,3) = adapthisteq(out(:,:,3), 'ClipLimit', clipLimit, 'NumTiles', [tileSz tileSz]);
            else
                out(:,:,1) = histeq(out(:,:,1));
                out(:,:,2) = histeq(out(:,:,2));
                out(:,:,3) = histeq(out(:,:,3));
            end

        case "Y channel (YCbCr)"
            if size(I,3)~=3
                % grayscale: just equalize it
                if useCLAHE
                    out = adapthisteq(I, 'ClipLimit', clipLimit, 'NumTiles', [tileSz tileSz]);
                else
                    out = histeq(I);
                end
            else
                YCC = rgb2ycbcr(I);
                Y   = YCC(:,:,1);
                if useCLAHE
                    Yeq = adapthisteq(Y, 'ClipLimit', clipLimit, 'NumTiles', [tileSz tileSz]);
                else
                    Yeq = histeq(Y);
                end 
                YCC(:,:,1) = Yeq;
                out = ycbcr2rgb(YCC);
            end

        otherwise
            error("Unknown equalization mode: " + mode);
    end

    app.ImgWorking = app.ensureUint8(out);
    app.showWorking();

    % Replace the ternary with if/else
    if useCLAHE
        tag = 'CLAHE';
    else
        tag = 'histeq';
    end
    app.setStatus(sprintf('Histogram equalization (%s): %s.', tag, mode));

catch ME
    app.setStatus('Histogram equalization failed.');
    uialert(app.UIFigure, ME.message, 'Enhancement Error');
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'UIAxes2')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'UIAxes')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EnhancementTab = uitab(app.TabGroup);
            app.EnhancementTab.Title = 'Enhancement';
            app.brightnessSliderLabel = uilabel(app.EnhancementTab);
            app.brightnessSliderLabel.Text = 'brightnessSlider';
            app.brightnessSlider = uislider(app.EnhancementTab);
            app.brightnessSlider.Limits = [-100 100];
            app.brightnessSlider.ValueChangedFcn = createCallbackFcn(app, @brightnessSliderValueChanged2, true);
            app.brightnessSlider.ValueChangingFcn = createCallbackFcn(app, @brightnessSliderValueChanging, true);
            app.contrastSliderLabel = uilabel(app.EnhancementTab);
            app.contrastSliderLabel.Text = 'contrastSlider';
            app.contrastSlider = uislider(app.EnhancementTab);
            app.contrastSlider.Limits = [-100 100];
            app.contrastSlider.ValueChangedFcn = createCallbackFcn(app, @contrastSliderValueChanged2, true);
            app.contrastSlider.ValueChangingFcn = createCallbackFcn(app, @contrastSliderValueChanging, true);
            app.gammaSliderLabel = uilabel(app.EnhancementTab);
            app.gammaSliderLabel.Text = 'gammaSlider';
            app.gammaSlider = uislider(app.EnhancementTab);
            app.gammaSlider.Limits = [0.2 3];
            app.gammaSlider.ValueChangedFcn = createCallbackFcn(app, @gammaSliderValueChanged2, true);
            app.gammaSlider.ValueChangingFcn = createCallbackFcn(app, @gammaSliderValueChanging, true);
            app.gammaSlider.Value = 0.2;
            app.applyBCGButton = uibutton(app.EnhancementTab, 'push');
            app.applyBCGButton.ButtonPushedFcn = createCallbackFcn(app, @applyBCGButtonPushed, true);
            app.applyBCGButton.Text = 'applyBCGButton';
            app.resetBCGButton = uibutton(app.EnhancementTab, 'push');
            app.resetBCGButton.ButtonPushedFcn = createCallbackFcn(app, @resetBCGButtonPushed, true);
            app.resetBCGButton.Text = 'resetBCGButton';
            app.equalizeModeDropDownLabel = uilabel(app.EnhancementTab);
            app.equalizeModeDropDownLabel.Text = 'equalizeModeDropDown';
            app.equalizeModeDropDown = uidropdown(app.EnhancementTab);
            app.equalizeModeDropDown.Items = {'Grayscale (auto)', 'Per-channel RGB', 'Y channel (YCbCr)'};
            app.equalizeModeDropDown.Value = 'Grayscale (auto)';
            app.EqualizeHistogramButton = uibutton(app.EnhancementTab, 'push');
            app.EqualizeHistogramButton.ButtonPushedFcn = createCallbackFcn(app, @EqualizeHistogramButtonPushed, true);
            app.EqualizeHistogramButton.Text = 'Equalize Histogram';
            app.UseCLAHECheckBox = uicheckbox(app.EnhancementTab);
            app.UseCLAHECheckBox.Text = 'Use CLAHE';
            app.ClipLimitEditFieldLabel = uilabel(app.EnhancementTab);
            app.ClipLimitEditFieldLabel.Text = 'ClipLimit';
            app.ClipLimitEditField = uieditfield(app.EnhancementTab, 'numeric');
            app.ClipLimitEditField.Value = 0.01;
            app.TileSizeEditFieldLabel = uilabel(app.EnhancementTab);
            app.TileSizeEditFieldLabel.Text = 'TileSize';
            app.TileSizeEditField = uieditfield(app.EnhancementTab, 'numeric');
            app.TileSizeEditField.Value = 8;
            app.SpatialTab = uitab(app.TabGroup);
            app.SpatialTab.Title = 'Spatial';
            app.spatialFilterDropDownLabel = uilabel(app.SpatialTab);
            app.spatialFilterDropDownLabel.Text = 'spatialFilter';
            app.spatialFilterDrop = uidropdown(app.SpatialTab);
            app.spatialFilterDrop.Items = {'Box Mean', 'Weighted Gaussian', 'Median', 'Unsharp (Boost)', 'Laplacian (2nd)', 'Sobel (H)', 'Sobel (V)', 'Prewitt (H)', 'Prewitt (V)'};
            app.spatialFilterDrop.Value = 'Box Mean';
            app.KernalSizeEditFieldLabel = uilabel(app.SpatialTab);
            app.KernalSizeEditFieldLabel.Text = 'Kernal Size';
            app.kernelSizeField = uieditfield(app.SpatialTab, 'numeric');
            app.kernelSizeField.Value = 3;
            app.SigmaStrengthEditFieldLabel = uilabel(app.SpatialTab);
            app.SigmaStrengthEditFieldLabel.Text = 'Sigma / Strength';
            app.sigmaField = uieditfield(app.SpatialTab, 'numeric');
            app.sigmaField.Value = 1;
            app.ApplySpatialButton = uibutton(app.SpatialTab, 'push');
            app.ApplySpatialButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySpatialButtonPushed, true);
            app.ApplySpatialButton.Text = 'Apply Spatial';
            app.FrequencyTab = uitab(app.TabGroup);
            app.FrequencyTab.Title = 'Frequency';
            app.freqTypeDropDownLabel = uilabel(app.FrequencyTab);
            app.freqTypeDropDownLabel.Text = 'freqTypeDropDown';
            app.freqTypeDropDown = uidropdown(app.FrequencyTab);
            app.freqTypeDropDown.Items = {'Ideal', 'Butterworth', 'Gaussian'};
            app.freqTypeDropDown.Value = 'Ideal';
            app.passTypeDropDownLabel = uilabel(app.FrequencyTab);
            app.passTypeDropDownLabel.Text = 'passTypeDropDown';
            app.passTypeDropDown = uidropdown(app.FrequencyTab);
            app.passTypeDropDown.Items = {'Low-Pass', 'High-Pass'};
            app.passTypeDropDown.Value = 'Low-Pass';
            app.CutoffD0EditFieldLabel = uilabel(app.FrequencyTab);
            app.CutoffD0EditFieldLabel.Text = 'Cutoff D0';
            app.cutoffField = uieditfield(app.FrequencyTab, 'numeric');
            app.OrdernEditFieldLabel = uilabel(app.FrequencyTab);
            app.OrdernEditFieldLabel.Text = 'Order n';
            app.orderField = uieditfield(app.FrequencyTab, 'numeric');
            app.ApplyFrequencyButton = uibutton(app.FrequencyTab, 'push');
            app.ApplyFrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFrequencyButtonPushed, true);
            app.ApplyFrequencyButton.Text = 'Apply Frequency';
            app.ColorTab = uitab(app.TabGroup);
            app.ColorTab.Title = 'Color';
            app.toHSIButtonPushed = uibutton(app.ColorTab, 'push');
            app.toHSIButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @toHSIButtonPushedPushed, true);
            app.toHSIButtonPushed.Text = 'To HSI';
            app.toLabButtonPushed = uibutton(app.ColorTab, 'push');
            app.toLabButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @toLabButtonPushedPushed, true);
            app.toLabButtonPushed.Text = 'To L*a*b*';
            app.toYCbCrButtonPushed = uibutton(app.ColorTab, 'push');
            app.toYCbCrButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @toYCbCrButtonPushedPushed, true);
            app.toYCbCrButtonPushed.Text = 'To YCbCr';
            app.backToRGBButtonPushed = uibutton(app.ColorTab, 'push');
            app.backToRGBButtonPushed.ButtonPushedFcn = createCallbackFcn(app, @backToRGBButtonPushedPushed, true);
            app.backToRGBButtonPushed.Text = 'Back to RGB';
            app.AnalyzeTab = uitab(app.TabGroup);
            app.AnalyzeTab.Title = 'Analyze';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CvTask1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end